classdef PhotogrammetryRobotApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        robotinterfacefigure      matlab.ui.Figure
        image                     matlab.ui.control.Image
        configpanel               matlab.ui.container.Panel
        camaralamp                matlab.ui.control.Lamp
        camaralamplabel           matlab.ui.control.Label
        ledlightlamp              matlab.ui.control.Lamp
        ledlightlamplabel         matlab.ui.control.Label
        motoreslamp               matlab.ui.control.Lamp
        motoreslamplabel          matlab.ui.control.Label
        reconnectcambutton        matlab.ui.control.Button
        reconnectlumbutton        matlab.ui.control.Button
        reconnectmotbutton        matlab.ui.control.Button
        closeappbutton            matlab.ui.control.Button
        autosystempanel           matlab.ui.container.Panel
        zinitstepspinner          matlab.ui.control.Spinner
        zinitsteplabel            matlab.ui.control.Label
        xinitstepspinner          matlab.ui.control.Spinner
        xinitsteplabel            matlab.ui.control.Label
        yinitstepspinner          matlab.ui.control.Spinner
        yinitsteplabel            matlab.ui.control.Label
        initsteplabel             matlab.ui.control.Label
        progresseditfield         matlab.ui.control.EditField
        progresslabel             matlab.ui.control.Label
        stopbutton                matlab.ui.control.StateButton
        startbutton               matlab.ui.control.StateButton
        multispectralswitch       matlab.ui.control.Switch
        multispectrallabel        matlab.ui.control.Label
        zsteplabel                matlab.ui.control.Label
        ysteplabel                matlab.ui.control.Label
        xsteplabel                matlab.ui.control.Label
        zstepspinner              matlab.ui.control.Spinner
        ystepspinner              matlab.ui.control.Spinner
        znumstepspinner           matlab.ui.control.Spinner
        znumsteplabel             matlab.ui.control.Label
        xnumstepspinner           matlab.ui.control.Spinner
        xnumsteplabel             matlab.ui.control.Label
        ynumstepspinner           matlab.ui.control.Spinner
        ynumsteplabel             matlab.ui.control.Label
        stepsizelabel             matlab.ui.control.Label
        zposspinner               matlab.ui.control.Spinner
        zposlabel                 matlab.ui.control.Label
        xstepspinner              matlab.ui.control.Spinner
        steplabel                 matlab.ui.control.Label
        patheditfield             matlab.ui.control.EditField
        pathlabel                 matlab.ui.control.Label
        yposspinner               matlab.ui.control.Spinner
        yposspinnerlabel          matlab.ui.control.Label
        xposspinner               matlab.ui.control.Spinner
        xposspinnerlabel          matlab.ui.control.Label
        initposlabel              matlab.ui.control.Label
        ledlightcontrolpanel      matlab.ui.container.Panel
        wavelengthlabel           matlab.ui.control.Label
        waitsequencespinner       matlab.ui.control.Spinner
        waitsequencespinnerlabel  matlab.ui.control.Label
        sequencebutton            matlab.ui.control.StateButton
        ledlightstatelamp         matlab.ui.control.Lamp
        ledlightstatelamplabel    matlab.ui.control.Label
        ledlightslider            matlab.ui.control.Slider
        cameracontrolpanel        matlab.ui.container.Panel
        openliveviewbutton        matlab.ui.control.StateButton
        focusbutton               matlab.ui.control.Button
        isosliderlabel            matlab.ui.control.Label
        waitfocusspinner          matlab.ui.control.Spinner
        waitfocusspinnerlabel     matlab.ui.control.Label
        isoslider                 matlab.ui.control.Slider
        isolabel                  matlab.ui.control.Label
        fnumberslider             matlab.ui.control.Slider
        fnumbersliderlabel        matlab.ui.control.Label
        shutterslider             matlab.ui.control.Slider
        shuttersliderlabel        matlab.ui.control.Label
        picturelabel              matlab.ui.control.Label
        capturebutton             matlab.ui.control.Button
        autofocusbutton           matlab.ui.control.Button
        focusstepspinner          matlab.ui.control.Spinner
        focusstepspinnerlabel     matlab.ui.control.Label
        picturepatheditfield      matlab.ui.control.EditField
        filepathlabel             matlab.ui.control.Label
        filenameeditfield         matlab.ui.control.EditField
        filenameeditfieldlabel    matlab.ui.control.Label
        motorcontrolpanel         matlab.ui.container.Panel
        joystickpausebutton       matlab.ui.control.StateButton
        joystickcontrolbutton     matlab.ui.control.StateButton
        stopmotorbutton           matlab.ui.control.StateButton
        zspinner                  matlab.ui.control.Spinner
        zspinnerlabel             matlab.ui.control.Label
        yspinner                  matlab.ui.control.Spinner
        yspinnerlabel             matlab.ui.control.Label
        updatebutton              matlab.ui.control.StateButton
        zaxisslider               matlab.ui.control.Slider
        zaxissliderlabel          matlab.ui.control.Label
        xspinner                  matlab.ui.control.Spinner
        xspinnerlabel             matlab.ui.control.Label
        yrightbutton              matlab.ui.control.Button
        zleftbutton               matlab.ui.control.Button
        yleftbutton               matlab.ui.control.Button
        xleftbutton               matlab.ui.control.Button
        xrightbutton              matlab.ui.control.Button
        zrightbutton              matlab.ui.control.Button
        xyposaxes                 matlab.ui.control.UIAxes
        maintitlelabel            matlab.ui.control.Label
    end

    properties (Access = private)
        pgrobot; % To hold photogrammetry bot class
    end
    
    methods (Access = private)
        function initializevalues(app)
            app.flaglampstatus(app.pgrobot.motorFlag, app.motoreslamp);
            app.flaglampstatus(app.pgrobot.lightFlag, app.ledlightlamp);
            app.flaglampstatus(app.pgrobot.cameraFlag, app.camaralamp);
            app.updatecomponents;
            app.pgrobot.previewstart;
        end
        
        function updatecomponents(app)
            app.updatespinnervalue(app.xspinner, 'X');
            app.updatespinnervalue(app.yspinner, 'Y');
            app.updatespinnervalue(app.zspinner, 'Z');
            app.updatespinnervalue(app.zaxisslider, 'Z');
            app.updategraph;
        end
        
        function updatespinnervalue(app, spinner, axisStr)
            calbStatus = app.pgrobot.getcalibratedstatus(axisStr);
            spinner.Value = calbStatus.CurPosition;
        end
        
        function updategraph(app)
            xCalbStatus = app.pgrobot.getcalibratedstatus('X');
            yCalbStatus = app.pgrobot.getcalibratedstatus('Y');
            plotHandle = plot(app.xyposaxes,0,0,'Marker','*','MarkerSize', 10,'LineWidth',5,'Color','red');
            try
                 set(plotHandle,'XData',yCalbStatus.CurPosition,'YData',xCalbStatus.CurPosition);
            catch
                 disp('Invalid or deleted object. No figure detected. Closing program.');
            end
        end
        
        function setautosystem(app)
            sessionPath = app.patheditfield.Value;
            
            initPosition(1) = app.xposspinner.Value; % + app.xinitstepspinner.Value*app.xstepspinner.Value;
            initPosition(2) = app.yposspinner.Value; % + app.yinitstepspinner.Value*app.ystepspinner.Value;
            initPosition(3) = abs(app.zposspinner.Value - 712); % + -app.zinitstepspinner.Value*app.zstepspinner.Value;
            
            nStep(1) = app.xnumstepspinner.Value; % - app.xinitstepspinner.Value;
            nStep(2) = app.ynumstepspinner.Value; % - app.yinitstepspinner.Value;
            nStep(3) = app.znumstepspinner.Value; % - app.zinitstepspinner.Value;
            
            stepDistance(1) = app.xstepspinner.Value; % * -1^(app.yinitstepspinner.Value) * -1^(app.zinitstepspinner.Value);
            stepDistance(2) = app.ystepspinner.Value; % * -1^(app.zinitstepspinner.Value);
            stepDistance(3) = -app.zstepspinner.Value;

            data = {sessionPath, initPosition, nStep, stepDistance};
            components = {app.stopbutton, app.startbutton, app.progresseditfield, app.ledlightstatelamp, app.ledlightslider};
            
            app.pgrobot.startautosystem(data, components, @app.updatecomponents);
        end
    end
    
    methods (Static, Access = private)
        function flaglampstatus(flag, lamp)
            if flag == 0
                lamp.Color = 'r';
            else
                lamp.Color = 'g';
            end
        end
        
        % Determine which discrete option the current value is closest to.
            % move the slider to that option, override the selected value
        function [finalValue] = adjustslider(value, event)
            [~, minIdx] = min(abs(value - event.Source.MajorTicks(:)));
            event.Source.Value = event.Source.MajorTicks(minIdx);
            finalValue = event.Source.MajorTicks(minIdx);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.pgrobot = PhotogrammetryRobot;
            app.initializevalues;
        end

        % Button pushed function: reconnectmotbutton
        function reconnectmotbuttonpushed(app, event)
            app.pgrobot.connectmotors;
            app.flaglampstatus(app.pgrobot.motorFlag, app.motoreslamp);
        end

        % Button pushed function: reconnectlumbutton
        function reconnectlumbuttonpushed(app, event)
            app.pgrobot.connectarduino;
            app.flaglampstatus(app.pgrobot.lightFlag, app.ledlightlamp);
        end

        % Button pushed function: reconnectcambutton
        function reconnectcambuttonpushed(app, event)
            app.pgrobot.connectcamera;
            app.flaglampstatus(app.pgrobot.cameraFlag, app.camaralamp);
        end

        % Value changed function: updatebutton
        function updatebuttonvaluechanged(app, event)
           xPos = app.xspinner.Value;
           yPos = app.yspinner.Value;
           zPos = abs(app.zspinner.Value - 712);
            
           app.pgrobot.moveefector([xPos, yPos, zPos]);
           app.updatecomponents;
        end

        % Button pushed function: xrightbutton
        function xrightbuttonpushed(app, event)
            app.pgrobot.leftmotor('X', 0.5);
            app.updatespinnervalue(app.xspinner, 'X');
            app.updategraph;
        end

        % Button pushed function: xleftbutton
        function xleftbuttonpushed(app, event)
            app.pgrobot.rightmotor('X', 0.5);
            app.updatespinnervalue(app.xspinner, 'X');
            app.updategraph;
        end

        % Button pushed function: yrightbutton
        function yrightbuttonpushed(app, event)
            app.pgrobot.leftmotor('Y', 0.5);
            app.updatespinnervalue(app.yspinner, 'Y');
            app.updategraph;
        end

        % Button pushed function: yleftbutton
        function yleftbuttonpushed(app, event)
            app.pgrobot.rightmotor('Y', 0.5);
            app.updatespinnervalue(app.yspinner, 'Y');
            app.updategraph;
        end

        % Button pushed function: zleftbutton
        function zleftbuttonpushed(app, event)
            app.pgrobot.leftmotor('Z', 0.5);
            app.updatespinnervalue(app.zspinner, 'Z');
            app.updatespinnervalue(app.zaxisslider, 'Z');
        end

        % Button pushed function: zrightbutton
        function zrightbuttonpushed(app, event)
            app.pgrobot.rightmotor('Z', 0.5);
            app.updatespinnervalue(app.zspinner, 'Z');
            app.updatespinnervalue(app.zaxisslider, 'Z');
        end

        % Value changed function: zaxisslider
        function zaxisslidervaluechanged(app, event)
            value = abs(app.zaxisslider.Value - 712);
            app.pgrobot.movemotor('Z', value);
            app.updatespinnervalue(app.zspinner, 'Z');
            app.updatespinnervalue(app.zaxisslider, 'Z');
        end

        % Value changed function: joystickcontrolbutton
        function joystickcontrolbuttonpushed(app, event)
            if app.joystickcontrolbutton.Value
                app.joystickpausebutton.Enable = 'on';
                app.joystickcontrolbutton.Text = 'Terminar Control por Joystick';
                app.joystickcontrolbutton.BackgroundColor = 'red';
            else
                app.joystickpausebutton.Enable = 'off';
                app.joystickcontrolbutton.Text = 'Empezar Control por Joystick';
                app.joystickcontrolbutton.BackgroundColor = 'green';
            end
            
            components = {app.joystickcontrolbutton, app.joystickpausebutton};
            app.pgrobot.joystickmotorcontrol(components, @app.updatecomponents);
        end

        % Value changed function: joystickpausebutton
        function joystickpausebuttonpushed(app, event)
            if app.joystickpausebutton.Value
                app.joystickcontrolbutton.Enable = 'off';
                app.joystickpausebutton.Text = 'Reanudar Control por Joystick';
            else
                app.joystickcontrolbutton.Enable = 'on';
                app.joystickpausebutton.Text = 'Pausar Control por Joystick';
            end
        end

        % Value changed function: ledlightslider
        function ledlightslidervaluechanged(app, event)
            ledValue = app.ledlightslider.Value;
            value = app.adjustslider(ledValue, event);
            app.pgrobot.ledlight(value);
            app.flaglampstatus(value, app.ledlightstatelamp);
        end

        % Value changed function: sequencebutton
        function sequencebuttonvaluechanged(app, event)
            app.ledlightstatelamp.Color = 'g';
            wait = app.waitsequencespinner.Value;
            for r = 8:-1:0
                app.pgrobot.ledlight(r);
                app.ledlightslider.Value = r; %%%%%%%%%%
                pause(wait);
            end
            app.ledlightstatelamp.Color = 'r';
        end

        % Button pushed function: capturebutton
        function capturebuttonpushed(app, event)
            imageName = app.filenameeditfield.Value;
            sessionPath = app.picturepatheditfield.Value;
            app.pgrobot.capture(append(sessionPath,'\',imageName,'.jpg'));
        end

        % Value changed function: openliveviewbutton
        function openliveviewbuttonvaluechanged(app, event)
            if app.openliveviewbutton.Value
                app.openliveviewbutton.Text = 'Cerrar Live View';
                app.openliveviewbutton.BackgroundColor = 'red';
                app.pgrobot.previewstart;
            else
                app.openliveviewbutton.Text = 'Abrir Live View';
                app.openliveviewbutton.BackgroundColor = [0.06,1.00,1.00];
                app.pgrobot.previewstop;
            end
        end

        % Value changed function: startbutton
        function startbuttonpushed(app, event)
            if ~app.startbutton.Value
                app.startbutton.Text = 'Pausar';
                app.stopbutton.Text = 'Terminar';
                app.startbutton.BackgroundColor = 'magenta';
                app.stopbutton.BackgroundColor = 'red';
                app.stopbutton.Value = true;
                app.stopbutton.Enable = 'on';
                if app.pgrobot.autoSystemFlag == true
                    app.pgrobot.autoSystemFlag = false;
                    app.setautosystem;
                    app.pgrobot.autoSystemFlag = true;
                    disp("Done");
                end
            else
                app.startbutton.Text = 'Reanudar';
                app.startbutton.BackgroundColor = 'magenta';
            end
        end

        % Value changed function: stopbutton
        function stopbuttonvaluechanged(app, event)
            if ~app.stopbutton.Value
                app.stopbutton.Enable = 'off';
                app.stopbutton.Text = 'Terminar';
                app.startbutton.Text = 'Empezar';
                app.startbutton.BackgroundColor = 'green';
                app.startbutton.Value = true;
                app.pgrobot.autoSystemFlag = true;
            else
                app.stopbutton.Enable = 'on';
                app.stopbutton.Text = 'Terminar';
            end
        end

        % Button pushed function: closeappbutton
        function closeappbuttonpushed(app, event)
            disp('Closing App');
            app.pgrobot.closerobot;
            try
                app.pgrobot.previewstop;
            catch
                disp('Preview already closed.');
            end
            close(app.robotinterfacefigure);
            disp('Done');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.robotinterfacefigure = uifigure('Visible', 'off');
            app.robotinterfacefigure.Name = 'MATLAB App';
            app.maintitlelabel = uilabel(app.robotinterfacefigure);
            app.maintitlelabel.Text = 'Aplicación de Control para Sistema de Fotogrametría Multiespectral';
            app.motorcontrolpanel = uipanel(app.robotinterfacefigure);
            app.motorcontrolpanel.Title = 'Control Motores';
            app.xyposaxes = uiaxes(app.motorcontrolpanel);
            xlabel(app.xyposaxes, 'EJE Y (mm)')
            ylabel(app.xyposaxes, 'EJE X (mm)')
            zlabel(app.xyposaxes, 'Z')
            app.xyposaxes.XLim = [0 740];
            app.xyposaxes.YLim = [0 740];
            app.zrightbutton = uibutton(app.motorcontrolpanel, 'push');
            app.zrightbutton.ButtonPushedFcn = createCallbackFcn(app, @zrightbuttonpushed, true);
            app.zrightbutton.Text = '↓z-';
            app.xrightbutton = uibutton(app.motorcontrolpanel, 'push');
            app.xrightbutton.ButtonPushedFcn = createCallbackFcn(app, @xrightbuttonpushed, true);
            app.xrightbutton.Text = 'x→';
            app.xleftbutton = uibutton(app.motorcontrolpanel, 'push');
            app.xleftbutton.ButtonPushedFcn = createCallbackFcn(app, @xleftbuttonpushed, true);
            app.xleftbutton.Text = '←x';
            app.yleftbutton = uibutton(app.motorcontrolpanel, 'push');
            app.yleftbutton.ButtonPushedFcn = createCallbackFcn(app, @yleftbuttonpushed, true);
            app.yleftbutton.Text = '↓y';
            app.zleftbutton = uibutton(app.motorcontrolpanel, 'push');
            app.zleftbutton.ButtonPushedFcn = createCallbackFcn(app, @zleftbuttonpushed, true);
            app.zleftbutton.Text = '↑z+';
            app.yrightbutton = uibutton(app.motorcontrolpanel, 'push');
            app.yrightbutton.ButtonPushedFcn = createCallbackFcn(app, @yrightbuttonpushed, true);
            app.yrightbutton.Text = '↑y';
            app.xspinnerlabel = uilabel(app.motorcontrolpanel);
            app.xspinnerlabel.Text = 'X';
            app.xspinner = uispinner(app.motorcontrolpanel);
            app.xspinner.Limits = [-5 740];
            app.zaxissliderlabel = uilabel(app.motorcontrolpanel);
            app.zaxissliderlabel.Text = {'EJE '; '   Z'};
            app.zaxisslider = uislider(app.motorcontrolpanel);
            app.zaxisslider.Limits = [0 740];
            app.zaxisslider.ValueChangedFcn = createCallbackFcn(app, @zaxisslidervaluechanged, true);
            app.updatebutton = uibutton(app.motorcontrolpanel, 'state');
            app.updatebutton.ValueChangedFcn = createCallbackFcn(app, @updatebuttonvaluechanged, true);
            app.updatebutton.Text = 'Nueva posición';
            app.yspinnerlabel = uilabel(app.motorcontrolpanel);
            app.yspinnerlabel.Text = 'Y';
            app.yspinner = uispinner(app.motorcontrolpanel);
            app.yspinner.Limits = [-5 740];
            app.zspinnerlabel = uilabel(app.motorcontrolpanel);
            app.zspinnerlabel.Text = 'Z';
            app.zspinner = uispinner(app.motorcontrolpanel);
            app.zspinner.Limits = [-5 740];
            app.stopmotorbutton = uibutton(app.motorcontrolpanel, 'state');
            app.stopmotorbutton.Enable = 'off';
            app.stopmotorbutton.Text = 'STOP MOTOR';
            app.joystickcontrolbutton = uibutton(app.motorcontrolpanel, 'state');
            app.joystickcontrolbutton.ValueChangedFcn = createCallbackFcn(app, @joystickcontrolbuttonpushed, true);
            app.joystickcontrolbutton.Text = 'Empezar Control por Joystick';
            app.joystickpausebutton = uibutton(app.motorcontrolpanel, 'state');
            app.joystickpausebutton.ValueChangedFcn = createCallbackFcn(app, @joystickpausebuttonpushed, true);
            app.joystickpausebutton.Enable = 'off';
            app.joystickpausebutton.Text = 'Pausar Control por Joystick';
            app.cameracontrolpanel = uipanel(app.robotinterfacefigure);
            app.cameracontrolpanel.Title = 'Control Cámara';
            app.filenameeditfieldlabel = uilabel(app.cameracontrolpanel);
            app.filenameeditfieldlabel.Text = 'Nombre de archivo';
            app.filenameeditfield = uieditfield(app.cameracontrolpanel, 'text');
            app.filenameeditfield.Value = 'test';
            app.filepathlabel = uilabel(app.cameracontrolpanel);
            app.filepathlabel.Text = 'Directorio de destino';
            app.picturepatheditfield = uieditfield(app.cameracontrolpanel, 'text');
            app.picturepatheditfield.Value = 'C:\DSLR\Capturas';
            app.focusstepspinnerlabel = uilabel(app.cameracontrolpanel);
            app.focusstepspinnerlabel.Enable = 'off';
            app.focusstepspinnerlabel.Text = 'Pasos';
            app.focusstepspinner = uispinner(app.cameracontrolpanel);
            app.focusstepspinner.Enable = 'off';
            app.autofocusbutton = uibutton(app.cameracontrolpanel, 'push');
            app.autofocusbutton.Enable = 'off';
            app.autofocusbutton.Text = 'AutoFocus';
            app.capturebutton = uibutton(app.cameracontrolpanel, 'push');
            app.capturebutton.ButtonPushedFcn = createCallbackFcn(app, @capturebuttonpushed, true);
            app.capturebutton.Text = 'Capturar Fotografía';
            app.picturelabel = uilabel(app.cameracontrolpanel);
            app.picturelabel.Text = 'Fotografía';
            app.shuttersliderlabel = uilabel(app.cameracontrolpanel);
            app.shuttersliderlabel.Enable = 'off';
            app.shuttersliderlabel.Text = 'Velocidad de obturación';
            app.shutterslider = uislider(app.cameracontrolpanel);
            app.shutterslider.Limits = [0.0166666666666667 1];
            app.shutterslider.MajorTicks = [0.0166666666666667 0.25 0.5 0.8 1];
            app.shutterslider.MajorTickLabels = {'1/60', '1/4', '1/2', '0.8', '1'};
            app.shutterslider.MinorTicks = [];
            app.shutterslider.Enable = 'off';
            app.shutterslider.Value = 0.0166666666666667;
            app.fnumbersliderlabel = uilabel(app.cameracontrolpanel);
            app.fnumbersliderlabel.Enable = 'off';
            app.fnumbersliderlabel.Text = 'Apertura (fnumber)';
            app.fnumberslider = uislider(app.cameracontrolpanel);
            app.fnumberslider.Limits = [4.5 29];
            app.fnumberslider.MajorTicks = [4.5 9 13 18 22 25 29];
            app.fnumberslider.MinorTicks = [];
            app.fnumberslider.Enable = 'off';
            app.fnumberslider.Value = 4.5;
            app.isolabel = uilabel(app.cameracontrolpanel);
            app.isolabel.Enable = 'off';
            app.isolabel.Text = 'ISO';
            app.isoslider = uislider(app.cameracontrolpanel);
            app.isoslider.Limits = [100 800];
            app.isoslider.MajorTicks = [100 200 400 800];
            app.isoslider.MajorTickLabels = {'100', '200', '400', '800'};
            app.isoslider.MinorTicks = [];
            app.isoslider.Enable = 'off';
            app.isoslider.Value = 100;
            app.waitfocusspinnerlabel = uilabel(app.cameracontrolpanel);
            app.waitfocusspinnerlabel.Enable = 'off';
            app.waitfocusspinnerlabel.Text = 'Espera';
            app.waitfocusspinner = uispinner(app.cameracontrolpanel);
            app.waitfocusspinner.Enable = 'off';
            app.isosliderlabel = uilabel(app.cameracontrolpanel);
            app.isosliderlabel.Enable = 'off';
            app.isosliderlabel.Text = 'FOCO:';
            app.focusbutton = uibutton(app.cameracontrolpanel, 'push');
            app.focusbutton.Enable = 'off';
            app.focusbutton.Text = 'Enfocar';
            app.openliveviewbutton = uibutton(app.cameracontrolpanel, 'state');
            app.openliveviewbutton.ValueChangedFcn = createCallbackFcn(app, @openliveviewbuttonvaluechanged, true);
            app.openliveviewbutton.Text = 'Cerrar Live View';
            app.openliveviewbutton.Value = true;
            app.ledlightcontrolpanel = uipanel(app.robotinterfacefigure);
            app.ledlightcontrolpanel.Title = 'Control Luminaria';
            app.ledlightslider = uislider(app.ledlightcontrolpanel);
            app.ledlightslider.Limits = [0 8];
            app.ledlightslider.MajorTicks = [0 1 2 3 4 5 6 7 8];
            app.ledlightslider.MajorTickLabels = {'0 (apagada)', '470', '528', '590', '617', '623', '640', '690', '740'};
            app.ledlightslider.ValueChangedFcn = createCallbackFcn(app, @ledlightslidervaluechanged, true);
            app.ledlightslider.MinorTicks = [];
            app.ledlightstatelamplabel = uilabel(app.ledlightcontrolpanel);
            app.ledlightstatelamplabel.Text = 'Apagada/ Encendida';
            app.ledlightstatelamp = uilamp(app.ledlightcontrolpanel);
            app.sequencebutton = uibutton(app.ledlightcontrolpanel, 'state');
            app.sequencebutton.ValueChangedFcn = createCallbackFcn(app, @sequencebuttonvaluechanged, true);
            app.sequencebutton.Text = 'Secuencia';
            app.waitsequencespinnerlabel = uilabel(app.ledlightcontrolpanel);
            app.waitsequencespinnerlabel.Text = 'Tiempo entre secuencia (s)';
            app.waitsequencespinner = uispinner(app.ledlightcontrolpanel);
            app.waitsequencespinner.Step = 0.1;
            app.waitsequencespinner.Limits = [0.1 5];
            app.waitsequencespinner.Value = 0.1;
            app.wavelengthlabel = uilabel(app.ledlightcontrolpanel);
            app.wavelengthlabel.Text = 'Longitud de onda (nm)';
            app.autosystempanel = uipanel(app.robotinterfacefigure);
            app.autosystempanel.Title = 'Sistema Automático';
            app.initposlabel = uilabel(app.autosystempanel);
            app.initposlabel.Text = 'Posición Inicial Cuadrícula (mm)';
            app.xposspinnerlabel = uilabel(app.autosystempanel);
            app.xposspinnerlabel.Text = 'X';
            app.xposspinner = uispinner(app.autosystempanel);
            app.xposspinner.Limits = [-1 740];
            app.xposspinner.Value = -1;
            app.yposspinnerlabel = uilabel(app.autosystempanel);
            app.yposspinnerlabel.Text = 'Y';
            app.yposspinner = uispinner(app.autosystempanel);
            app.yposspinner.Limits = [-1 740];
            app.yposspinner.Value = -1;
            app.pathlabel = uilabel(app.autosystempanel);
            app.pathlabel.Text = 'Directorio de destino';
            app.patheditfield = uieditfield(app.autosystempanel, 'text');
            app.patheditfield.Value = 'C:\DSLR\SessionX';
            app.steplabel = uilabel(app.autosystempanel);
            app.steplabel.Text = 'Paso (mm)';
            app.xstepspinner = uispinner(app.autosystempanel);
            app.xstepspinner.Limits = [0 500];
            app.zposlabel = uilabel(app.autosystempanel);
            app.zposlabel.Text = 'Z';
            app.zposspinner = uispinner(app.autosystempanel);
            app.zposspinner.Limits = [-1 740];
            app.zposspinner.Value = -1;
            app.stepsizelabel = uilabel(app.autosystempanel);
            app.stepsizelabel.Text = 'Tamaño cuadrícula (pasos)';
            app.ynumsteplabel = uilabel(app.autosystempanel);
            app.ynumsteplabel.Text = 'Ancho (Y)';
            app.ynumstepspinner = uispinner(app.autosystempanel);
            app.ynumstepspinner.Limits = [1 Inf];
            app.ynumstepspinner.Value = 1;
            app.xnumsteplabel = uilabel(app.autosystempanel);
            app.xnumsteplabel.Text = 'Largo (X)';
            app.xnumstepspinner = uispinner(app.autosystempanel);
            app.xnumstepspinner.Limits = [1 Inf];
            app.xnumstepspinner.Value = 1;
            app.znumsteplabel = uilabel(app.autosystempanel);
            app.znumsteplabel.Text = 'Alto (Z)';
            app.znumstepspinner = uispinner(app.autosystempanel);
            app.znumstepspinner.Limits = [1 Inf];
            app.znumstepspinner.Value = 1;
            app.ystepspinner = uispinner(app.autosystempanel);
            app.ystepspinner.Limits = [0 500];
            app.zstepspinner = uispinner(app.autosystempanel);
            app.zstepspinner.Limits = [0 500];
            app.xsteplabel = uilabel(app.autosystempanel);
            app.xsteplabel.Text = 'X';
            app.ysteplabel = uilabel(app.autosystempanel);
            app.ysteplabel.Text = 'Y';
            app.zsteplabel = uilabel(app.autosystempanel);
            app.zsteplabel.Text = 'Z';
            app.multispectrallabel = uilabel(app.autosystempanel);
            app.multispectrallabel.Text = 'Multiespectral';
            app.multispectralswitch = uiswitch(app.autosystempanel, 'slider');
            app.startbutton = uibutton(app.autosystempanel, 'state');
            app.startbutton.ValueChangedFcn = createCallbackFcn(app, @startbuttonpushed, true);
            app.startbutton.Text = 'Empezar';
            app.startbutton.Value = true;
            app.stopbutton = uibutton(app.autosystempanel, 'state');
            app.stopbutton.ValueChangedFcn = createCallbackFcn(app, @stopbuttonvaluechanged, true);
            app.stopbutton.Enable = 'off';
            app.stopbutton.Text = 'Terminar';
            app.stopbutton.Value = true;
            app.progresslabel = uilabel(app.autosystempanel);
            app.progresslabel.Text = 'Progreso:';
            app.progresseditfield = uieditfield(app.autosystempanel, 'text');
            app.progresseditfield.Editable = 'off';
            app.initsteplabel = uilabel(app.autosystempanel);
            app.initsteplabel.Enable = 'off';
            app.initsteplabel.Text = 'Coordenadas Punto Inicial (pasos)';
            app.yinitsteplabel = uilabel(app.autosystempanel);
            app.yinitsteplabel.Enable = 'off';
            app.yinitsteplabel.Text = 'Ancho (Y)';
            app.yinitstepspinner = uispinner(app.autosystempanel);
            app.yinitstepspinner.Limits = [0 Inf];
            app.yinitstepspinner.Enable = 'off';
            app.xinitsteplabel = uilabel(app.autosystempanel);
            app.xinitsteplabel.Enable = 'off';
            app.xinitsteplabel.Text = 'Largo (X)';
            app.xinitstepspinner = uispinner(app.autosystempanel);
            app.xinitstepspinner.Limits = [0 Inf];
            app.xinitstepspinner.Enable = 'off';
            app.zinitsteplabel = uilabel(app.autosystempanel);
            app.zinitsteplabel.Enable = 'off';
            app.zinitsteplabel.Text = 'Alto (Z)';
            app.zinitstepspinner = uispinner(app.autosystempanel);
            app.zinitstepspinner.Limits = [0 Inf];
            app.zinitstepspinner.Enable = 'off';
            app.configpanel = uipanel(app.robotinterfacefigure);
            app.closeappbutton = uibutton(app.configpanel, 'push');
            app.closeappbutton.ButtonPushedFcn = createCallbackFcn(app, @closeappbuttonpushed, true);
            app.closeappbutton.Text = 'Close App';
            app.reconnectmotbutton = uibutton(app.configpanel, 'push');
            app.reconnectmotbutton.ButtonPushedFcn = createCallbackFcn(app, @reconnectmotbuttonpushed, true);
            app.reconnectmotbutton.Text = 'Reconectar';
            app.reconnectlumbutton = uibutton(app.configpanel, 'push');
            app.reconnectlumbutton.ButtonPushedFcn = createCallbackFcn(app, @reconnectlumbuttonpushed, true);
            app.reconnectlumbutton.Text = 'Reconectar';
            app.reconnectcambutton = uibutton(app.configpanel, 'push');
            app.reconnectcambutton.ButtonPushedFcn = createCallbackFcn(app, @reconnectcambuttonpushed, true);
            app.reconnectcambutton.Text = 'Reconectar';
            app.motoreslamplabel = uilabel(app.configpanel);
            app.motoreslamplabel.Text = 'Motores ';
            app.motoreslamp = uilamp(app.configpanel);
            app.ledlightlamplabel = uilabel(app.configpanel);
            app.ledlightlamplabel.Text = 'Luminaria ';
            app.ledlightlamp = uilamp(app.configpanel);
            app.camaralamplabel = uilabel(app.configpanel);
            app.camaralamplabel.Text = 'Camara';
            app.camaralamp = uilamp(app.configpanel);
            app.image = uiimage(app.robotinterfacefigure);
            app.image.ImageSource = 'escudo.jpg';
            % Show the figure after all components are created
            app.robotinterfacefigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PhotogrammetryRobotApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.robotinterfacefigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.robotinterfacefigure)
        end
    end
end